classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        ReproducirButton         matlab.ui.control.Button
        ImportarsealPanel        matlab.ui.container.Panel
        ImportarButton           matlab.ui.control.Button
        GenerarsealPanel         matlab.ui.container.Panel
        GenerarButton            matlab.ui.control.Button
        TipodesealDropDown       matlab.ui.control.DropDown
        TipodesealDropDownLabel  matlab.ui.control.Label
        CapturasealPanel         matlab.ui.container.Panel
        GuardarButton            matlab.ui.control.Button
        GrabarButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        Tab2                     matlab.ui.container.Tab
    end


    properties (Access = public)
        x_in % Señal de audio
        sample_rate % Frecuencia de muestreo
        value % Opción seleccionada en el dropdown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            % Ingresar los parámetros para grabar el audio
            prompt = {'Duración (segundos):', 'Frecuencia de muestreo Fs (Hz):', 'Número total de canales (1: mono, 2: estéreo):'};
            dlgtitle = 'Recording Parameters';
            dims = [1 35];
            definput = {'3', '44100', '2'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Recuperar parámetros de la caja de diálogo
            duration = str2double(answer{1});
            app.sample_rate = str2double(answer{2});
            num_channels = str2double(answer{3});

            % Grabar audio
            recObj = audiorecorder(app.sample_rate, 16, num_channels);
            disp('Start recording...');
            recordblocking(recObj, duration);
            disp('Recording complete.');

            % Recuperar la señal de audio en un vector
            app.x_in = 32*getaudiodata(recObj);
            
            % Graficar Canal 1 en UIAxes de App Designer
            plot(app.UIAxes, app.x_in(:,1), 'v');
            title(app.UIAxes, 'Señal Estereofónica: Canal 1');
            xlabel(app.UIAxes, 'n');
            grid(app.UIAxes, 'on');

        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            % Gestionar acciones según respuesta
                    sound(app.x_in, app.sample_rate);

        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            % Ingresar los parámetros para grabar el audio
            prompt = {'Nombre del archivo:'};
            dlgtitle = 'Guardar archivo';
            dims = [1 35];
            definput = {'archivo'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            
            % Recuperar parámetros de la caja de diálogo
            nombre_archivo = (answer{1});

            nombre_archivo_completo = [nombre_archivo, '.wav'];
            audiowrite(nombre_archivo_completo, app.x_in, app.sample_rate);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportarButton
        function ImportarButtonPushed(app, event)
            [filename, path] = uigetfile({'*.mp3;*.wav'});
            figure(app.UIFigure);
            fullFileName = fullfile(path, filename);  % Construye la ruta completa del archivo
            
            % Recuperar los datos importados
            [app.x_in, app.sample_rate] = audioread(fullFileName);
            % sound(data, sample_rate);

            % Graficar Canal 1 en UIAxes de App Designer
            plot(app.UIAxes, app.x_in(:,1), 'v');
            title(app.UIAxes, 'Señal Estereofónica: Canal 1');
            xlabel(app.UIAxes, 'n');
            grid(app.UIAxes, 'on');
            
        end

        % Value changed function: TipodesealDropDown
        function TipodesealDropDownValueChanged(app, event)
            app.value = app.TipodesealDropDown.Value;
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            switch app.value
                case 'Chirp'
                    dlgtitle = 'Chirp Signal Parameters';
                    prompt = {'Amplitud:','Frecuencia inicial:','Frecuencia final:', 'Frecuencia de muestreo:' ,'Duración de la señal:'};
                    dims = [1 35];
                    defect_input = {'1','10','50','200','0.5'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    

                    A = str2num(answer{1});    % Amplitude of the chirp
                    f0 = str2num(answer{2});    % Initial frequency in Hz
                    f1 = str2num(answer{3});    % Final frequency in Hz
                    Fs = str2num(answer{4});    % Sampling frequency in Hz
                    T = str2num(answer{5});    % Duration of the signal in seconds
                    
                    t = 0:1/Fs:T;      % Time vector from 0 to T with steps of 1/Fs
                    x = chirp(t, f0, T, f1);
                    
                    % plot(app.UIAxes, app.x_in(:,1), 'v');

                    app.x_in = x;
                    app.sample_rate = t;


                    stem(app.UIAxes, t, x);
                    title(app.UIAxes, 'Señal Estereofónica: Canal 1');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
                    

                 case 'Seno'
                    disp('Se seleccionó la Seno');
                    dlgtitle = 'Sine Signal Parameters';
                    prompt = {'Ganancia:','Frecuencia Análoga:','Frecuencia Muestreo:', 'Fase (rad)' ,'Desplazamiento:','n_inicio:','n_final:' };
                    dims = [1 35];
                    defect_input = {'1','20','400','0','0', '0', '50'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    
                    A=str2num(answer{1}); % Ganancia de la exponencial
                    Fa=str2num(answer{2}); % Frecuencia análoga
                    Fs=str2num(answer{3}); % Frecuencia de muestreo
                    
                    Fase=str2num(answer{4}); % Fase de la señal análoga (Rad)
                    
                    n0=str2num(answer{5});
                    ni=str2num(answer{6});
                    nf=str2num(answer{7});
                    
                    % disp(answer{1});
                    % disp(answer{6});
                    
                    Ts=1/Fs; %Periodo de muestreo
                    
                    n6=ni:nf; %Instantes de tiempo
                    variable = (2*pi*Fa*(n6-n0)/Fs+ Fase);
                    result = 2*pi*Fa/Fs+ Fase
                    x7= A*[sin(2*pi*Fa*(n6-n0)/Fs+ Fase)]; %/(2*pi*Fa*(-55)/Fs+ Fase);
                    
                    stem(app.UIAxes, n6,x7);
                    % xlabel('n'); ylabel('x(n)'); title(['Señal Senoidal, Fa=' num2str(Fa) ' Fs= ' num2str(Fs)]);grid on
                    title(app.UIAxes, 'Señal Estereofónica: Canal 1');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
                 case 'Coseno'
                    disp('Se seleccionó la Coseno');
                    % Agregar código para manejar la selección de Opción 1
                    dlgtitle = 'Sine Signal Parameters';
                    prompt = {'Ganancia:','Frecuencia Análoga:','Frecuencia Muestreo:', 'Fase (rad)' ,'Desplazamiento:','n_inicio:','n_final:' };
                    dims = [1 35];
                    defect_input = {'1','20','400','0','0', '0', '50'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    
                    A=str2num(answer{1}); % Ganancia de la exponencial
                    Fa=str2num(answer{2}); % Frecuencia análoga
                    Fs=str2num(answer{3}); % Frecuencia de muestreo
                    
                    Fase=str2num(answer{4}); % Fase de la señal análoga (Rad)
                    
                    n0=str2num(answer{5});
                    ni=str2num(answer{6});
                    nf=str2num(answer{7});
                    
                    % disp(answer{1});
                    % disp(answer{6});
                    
                    Ts=1/Fs; %Periodo de muestreo
                    
                    n6=ni:nf; %Instantes de tiempo
                    variable = (2*pi*Fa*(n6-n0)/Fs+ Fase);
                    result = 2*pi*Fa/Fs+ Fase
                    x7= A*[cos(2*pi*Fa*(n6-n0)/Fs+ Fase)]; %/(2*pi*Fa*(-55)/Fs+ Fase);
                    
                    stem(app.UIAxes, n6,x7);
                    title(app.UIAxes, 'Señal Estereofónica: Canal 1');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
                 case 'Diente de sierra'
                    disp('Se seleccionó la Diente de sierra');
                    % Agregar código para manejar la selección de Opción 1
                                        dlgtitle = 'Sine Signal Parameters';
                    prompt = {'Ganancia:','Frecuencia Análoga:','Frecuencia Muestreo:', 'Fase (rad)' ,'Desplazamiento:','n_inicio:','n_final:' };
                    dims = [1 35];
                    defect_input = {'1','20','400','0','0', '0', '50'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    
                    A=str2num(answer{1}); % Ganancia de la exponencial
                    Fa=str2num(answer{2}); % Frecuencia análoga
                    Fs=str2num(answer{3}); % Frecuencia de muestreo
                    
                    Fase=str2num(answer{4}); % Fase de la señal análoga (Rad)
                    
                    n0=str2num(answer{5});
                    ni=str2num(answer{6});
                    nf=str2num(answer{7});
                    
                    % disp(answer{1});
                    % disp(answer{6});
                    
                    Ts=1/Fs; %Periodo de muestreo
                    
                    n6=ni:nf; %Instantes de tiempo
                    variable = (2*pi*Fa*(n6-n0)/Fs+ Fase);
                    result = 2*pi*Fa/Fs+ Fase;
                    x7 = A * sawtooth(2 * pi * Fa * (n6 - n0) / Fs + Fase);
                    
                    stem(app.UIAxes, n6,x7);
                    title(app.UIAxes, 'Diente de sierra');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
                    
                    
                 case 'Rampa'
                    disp('Se seleccionó la Rampa');
                    dlgtitle = 'Ramp Signal Parameters';
                    prompt = {'Pendiente:','Desplazamiento','n_inicio:','n_final:' };
                    dims = [1 35];
                    defect_input = {'1','2', '-5', '20'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    
                    A=str2num(answer{1}); % Pendiente de la rampa
                    n0=str2num(answer{2});
                    ni=str2num(answer{3});
                    nf=str2num(answer{4});
                    
                    
                    n3=ni:nf;  % Instantes de tiempo
                    x3=A*(n3-n0);
                    
                    
                    stem(app.UIAxes, n3,x3);
                    % xlabel('n'); ylabel('x(n)'); title(['Señal Senoidal, Fa=' num2str(Fa) ' Fs= ' num2str(Fs)]);grid on
                    title(app.UIAxes, 'Rampa');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
                 case 'Escalón'
                    disp('Se seleccionó la Escalón');
                    dlgtitle = 'Step Signal Parameters';
                    prompt = {'Amplitud:','Desplazamiento','n_inicio:','n_final:' };
                    dims = [1 35];
                    defect_input = {'1','2', '-5', '20'};
                    answer = inputdlg(prompt,dlgtitle,dims,defect_input);
                    
                    A=str2num(answer{1});
                    n0=str2num(answer{2});
                    ni=str2num(answer{3});
                    nf=str2num(answer{4});
                    
                    x2= A*[zeros(1,abs(ni)+n0)  ones(1, abs(nf)+1-n0)  ];
                    
                    n2=ni:nf;  % Instantes de tiempo

                    stem(app.UIAxes, n2,x2);
                    % xlabel('n'); ylabel('x(n)'); title(['Señal Senoidal, Fa=' num2str(Fa) ' Fs= ' num2str(Fs)]);grid on
                    title(app.UIAxes, 'Señal Estereofónica: Canal 1');
                    xlabel(app.UIAxes, 'n');
                    grid(app.UIAxes, 'on');
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.CapturasealPanel = uipanel(app.Tab);
            app.CapturasealPanel.Title = 'Captura señal';
            app.GrabarButton = uibutton(app.CapturasealPanel, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.GuardarButton = uibutton(app.CapturasealPanel, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.GenerarsealPanel = uipanel(app.Tab);
            app.GenerarsealPanel.Title = 'Generar señal';
            app.TipodesealDropDownLabel = uilabel(app.GenerarsealPanel);
            app.TipodesealDropDownLabel.Text = 'Tipo de señal';
            app.TipodesealDropDown = uidropdown(app.GenerarsealPanel);
            app.TipodesealDropDown.Items = {'---', 'Chirp', 'Seno', 'Coseno', 'Diente de sierra', 'Rampa', 'Escalón'};
            app.TipodesealDropDown.ValueChangedFcn = createCallbackFcn(app, @TipodesealDropDownValueChanged, true);
            app.TipodesealDropDown.Value = '---';
            app.GenerarButton = uibutton(app.GenerarsealPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.ImportarsealPanel = uipanel(app.Tab);
            app.ImportarsealPanel.Title = 'Importar señal';
            app.ImportarButton = uibutton(app.ImportarsealPanel, 'push');
            app.ImportarButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarButtonPushed, true);
            app.ImportarButton.Text = 'Importar';
            app.ReproducirButton = uibutton(app.Tab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end